classdef SolarAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Button                     matlab.ui.control.Button
        PasswordEditField          matlab.ui.control.EditField
        PasswordEditFieldLabel     matlab.ui.control.Label
        Hyperlink                  matlab.ui.control.Hyperlink
        AccederLabel               matlab.ui.control.Label
        NOMBREDELARCHIVOLabel      matlab.ui.control.Label
        NombredelArchivoEditField  matlab.ui.control.EditField
        IdiomaDropDown             matlab.ui.control.DropDown
        CalcularButton             matlab.ui.control.Button
        checkLabel2                matlab.ui.control.Label
        checkLabel                 matlab.ui.control.Label
        CargasButton               matlab.ui.control.Button
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        DIMENSIONAMIENTOFOTOVOLTAICOLabel  matlab.ui.control.Label
        InstruButton               matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        NoButton                   matlab.ui.control.Button
        SiButton                   matlab.ui.control.Button
        BienvenidoausuarioantesdesubirelarchivoLabel  matlab.ui.control.Label
        RadiacionButton            matlab.ui.control.Button
        SubirArchivoButton         matlab.ui.control.Button
        IdiomaLanguageLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = public)
        datos ;
        LabelHabilitado = false;
        LabelHabilitado1 = false;
        CheckLabel2Visible = false;
        RadiacionPromedioGlobal;


    end

    properties (Access = private)
        app2;
        Property
        Calculosapp
        file;
        path;
        App2Listener;
        app2app
        ConfiguracionListener;
        ConfiguracionApp
        Panelesapp
        InstruApp
        InstruListener;
        Soporteapp;
        bar;
        texto;
        lan;
        total = 0
        Language = 'es';
        Strings;

    end
    methods (Access = public)
        function actualizar1(app,bar,texto)
            app.bar.XData = bar;
            app.texto.String = texto;

            if app.ComenzarprocesoButton.Enable == "on"
                app.bar.XData = [0 1 1 0];
                app.texto.String = sprintf('%.0f%%',100);
            end
        end
        function HabilitarLabel(app)
            app.CargasButton.Enable = "on";
            app.CargasButton.Visible = "on";
            app.checkLabel.Visible = 'on';
            app.CalcularButton.Visible = "off";
            app.NOMBREDELARCHIVOLabel.Visible = "on";
            app.RadiacionButton.Enable = "on";
            app.RadiacionButton.Visible = "on";
            app.NombredelArchivoEditField.Visible = "on";
            app.NombredelArchivoEditField.Value = fullfile(app.file);
            app.bar.XData = [0 0.50 0.50 0];
            app.texto.String = sprintf('%.0f%%',50);

        end

        function HabilitarLabel1(app)
            app.checkLabel2.Visible = 'on';
            app.CalcularButton.Visible = "on";
            app.RadiacionButton.Enable = "on";
            app.RadiacionButton.Visible = "on";
            app.NombredelArchivoEditField.Visible = "on";
            app.NombredelArchivoEditField.Value = fullfile(app.file);
            app.bar.XData = [0 0.75 0.75 0];
            app.texto.String = sprintf('%.0f%%',75);

        end

    end

    methods (Access = private)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idiomaSeleccionado)
          
                       
            if app.LabelHabilitado
                app.checkLabel.Visible = 'on';
            else
                app.checkLabel.Visible = 'off';
            end
            if app.LabelHabilitado1
                app.checkLabel2.Visible = 'on';
            else
                app.checkLabel2.Visible = 'off';
            end
           
            IdiomaDropDownValueChanged(app, []);
            addpath('iconos\','imagenes\');
           
            app.bar = patch(app.UIAxes,[0 0 0 0],[0 0 1 1],[0.31 0.93 0.58]);
            app.texto = text(app.UIAxes,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            app.UIAxes.Toolbar.Visible = "off";
            disableDefaultInteractivity(app.UIAxes);
            app.CargasButton.Visible = 'off';
            app.CalcularButton.Visible = "off";
            app.NOMBREDELARCHIVOLabel.Visible= "off";


        end

        % Button pushed function: SubirArchivoButton
        function SubirArchivoButtonPushed(app, event)
            try
                [app.file,app.path]= uigetfile({'*.txt; *.xlsx; *.xls;', '*.txt,*.xlsx,*.xls'},  'ugetfile');
                app.UIFigure.Visible = "off";
                app.UIFigure.Visible = "on";
                d = uiprogressdlg(app.UIFigure,'Title', 'CHECK',"Indeterminate","on");
                app.datos = readtable(fullfile(app.path,app.file));
                close(d);
                uialert(app.UIFigure,  fullfile( 'CHECK') , app.file), 'confirmacion';'Icon';'success';
                app.CalcularButton.Visible = "off";
                app.RadiacionButton.Visible = "on";
                app.CargasButton.Visible = 'off';
                app.NombredelArchivoEditField.Visible = "on";
                app.NombredelArchivoEditField.Value = fullfile(app.file);
                app.NOMBREDELARCHIVOLabel.Visible = "on";
                app.bar.XData = [0 0.25 0.25 0];
                app.texto.String = sprintf('%.0f%%',25);
            catch
                uialert(app.UIFigure, 'X' ,  'informacion' ,'Icon',"info");
                app.NombredelArchivoEditField.Visible = "off";
                app.NOMBREDELARCHIVOLabel.Visible = "off";
                app.RadiacionButton.Visible = "off";
                app.CargasButton.Visible = 'off';
                app.CalcularButton.Visible = "off";
                app.bar.XData = [0 0 0 0];
                app.texto.String = sprintf('%.0f%%',0);

            end

        end

        % Button pushed function: RadiacionButton
        function RadiacionButtonPushed(app, event)
            app.RadiacionButton.Enable = "off";
            idiomaSeleccionado = app.IdiomaDropDown.Value;
            app.app2app = app2(app, idiomaSeleccionado);
            app.App2Listener = addlistener(app.app2app, 'ObjectBeingDestroyed', @(src, ~) app.HabilitarLabel());
            app.app2app.MenoApp = app;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.Panel.Visible = "off";
            app.InstruButton.Enable = "on";
            app.InstruButton.Visible = "on";
        end

        % Button pushed function: SiButton
        function SiButtonPushed(app, event)
                   app.InstruButton.Enable = "off";
            f = uiprogressdlg(app.UIFigure, "Title", "CHECK", "Indeterminate", "on");
            idiomaSeleccionado = app.IdiomaDropDown.Value;
            instruApp = instru(idiomaSeleccionado);
            instruApp.Menoapp = app;
            app.Panel.Visible = "off";
            app.InstruButton.Enable = "on";
            app.InstruButton.Visible = "on";
            close(f);
           
        end

        % Button pushed function: InstruButton
        function InstruButtonPushed(app, event)
            app.InstruButton.Enable = "off";
            f = uiprogressdlg(app.UIFigure, "Title", "CHECK", "Indeterminate", "on");
            idiomaSeleccionado = app.IdiomaDropDown.Value;
            instruApp = instru(idiomaSeleccionado);
            instruApp.Menoapp = app;
            close(f);
        end

        % Button pushed function: CargasButton
        function CargasButtonPushed(app, event)
            app.CargasButton.Enable = "off";
            Idioma=app.IdiomaDropDown.Value;
            configuracionApp = Configuracion(Idioma);
            configuracionApp.Menoapp = app;
            app.ConfiguracionListener = addlistener(configuracionApp.UIFigure, 'ObjectBeingDestroyed', @(src, ~) app.HabilitarLabel1());
            waitfor(configuracionApp.UIFigure);

        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
                               
              app.bar.XData = [0 1 1 0];
            app.texto.String = sprintf('%.0f%%',100);
           
            app.CalcularButton.Enable = "on";
            Idioma=app.IdiomaDropDown.Value;
            calculosApp = Calculos(Idioma);
            calculosApp.Menoapp = app;
            waitfor(calculosApp.UIFigure);
            if ~isempty(app.Calculosapp) && isvalid(app.Calculosapp)
                app.Calculosapp.AbrirCalculos();
            end
        end

        % Value changed function: IdiomaDropDown
        function IdiomaDropDownValueChanged(app, event)
            selectedLanguage = app.IdiomaDropDown.Value;
            global IdiomaSeleccionado;
            IdiomaSeleccionado = selectedLanguage;
            if ~isempty(app.app2)
                notify(app.app2, 'CambioIdioma', EventData(selectedLanguage));
                app.App2.ActualizarIdioma(selectedLanguage);
            end
            if strcmp(selectedLanguage, 'Español')
                app.Language = 'es';
                app.RadiacionButton.Text = 'Radiación';
                app.CargasButton.Text = 'Cargas';
                app.CalcularButton.Text = 'Calcular';
                app.SubirArchivoButton.Text = 'Subir Archivo';
                app.BienvenidoausuarioantesdesubirelarchivoLabel.Text = 'Bienvenido(a) usuario(a), ¿Es la primera vez usando este aplicativo?';
                app.InstruButton.Text = 'INSTRUCCIONES';
                app.NOMBREDELARCHIVOLabel.Text = 'Nombre del archivo';
                app.DIMENSIONAMIENTOFOTOVOLTAICOLabel.Text ='DIMENSIONAMIENTO FOTOVOLTAICO';
                app.Button.Text='ENTRAR';
                  app.AccederLabel.Text = "Ingrese la contraseña asignada en caso de no tenerla ingrese al link ..";
            elseif strcmp(selectedLanguage, 'English')
                app.Button.Text='Enter';
                app.Language = 'en';
                app.RadiacionButton.Text = 'Radiation';
                app.CargasButton.Text = 'Loads';
                app.CalcularButton.Text = 'Calculate';
                app.SubirArchivoButton.Text = 'Upload file';
                app.BienvenidoausuarioantesdesubirelarchivoLabel.Text = 'Welcome user, is this your first time using this application?';
                app.InstruButton.Text = 'INSTRUCTIONS';
                app.DIMENSIONAMIENTOFOTOVOLTAICOLabel.Text ='PHOTOVOLTAIC SIZING';
                app.NOMBREDELARCHIVOLabel.Text = 'File Name';
                
                app.AccederLabel.Text = "Enter the assigned password if you do not have it, enter the link...";
                app.Hyperlink.Text= "get your password";


            else
                app.Language = 'es';
            end


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            ps = strtrim(app.PasswordEditField.Value);
            prePS = 'AppFVUD23';
            if strcmp(ps, prePS)
                app.AccederLabel.Visible = "off";
                app.Hyperlink.Visible = "off";
                app.PasswordEditFieldLabel.Visible = "off";
                app.PasswordEditField.Visible = "off";
                app.Button.Visible = "off";
            else
                idiomaSeleccionado = app.IdiomaDropDown.Value;
                if strcmp(idiomaSeleccionado, 'English')

                    msgbox('Incorrect password.');
                else

                    msgbox('Contraseña incorrecta.');
                end
            end

        end

        % Value changed function: PasswordEditField
        function PasswordEditFieldValueChanged(app, event)
       
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.IdiomaLanguageLabel = uilabel(app.UIFigure);
            app.IdiomaLanguageLabel.Text = 'Idioma/Language';
            app.SubirArchivoButton = uibutton(app.UIFigure, 'push');
            app.SubirArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SubirArchivoButtonPushed, true);
            app.SubirArchivoButton.Icon = 'subirarc.png';
            app.SubirArchivoButton.Text = 'Subir Archivo';
            app.RadiacionButton = uibutton(app.UIFigure, 'push');
            app.RadiacionButton.ButtonPushedFcn = createCallbackFcn(app, @RadiacionButtonPushed, true);
            app.RadiacionButton.Icon = fullfile(pathToMLAPP, 'images', 'datosv.png');
            app.RadiacionButton.Visible = 'off';
            app.RadiacionButton.Text = {''; 'Radiacion'};
            app.Panel = uipanel(app.UIFigure);
            app.BienvenidoausuarioantesdesubirelarchivoLabel = uilabel(app.Panel);
            app.BienvenidoausuarioantesdesubirelarchivoLabel.Text = 'Bienvenido(a) usuario(a),  ¿Es la primera vez usando este aplicativo?';
            app.SiButton = uibutton(app.Panel, 'push');
            app.SiButton.ButtonPushedFcn = createCallbackFcn(app, @SiButtonPushed, true);
            app.SiButton.Text = 'Si';
            app.NoButton = uibutton(app.Panel, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.InstruButton = uibutton(app.UIFigure, 'push');
            app.InstruButton.ButtonPushedFcn = createCallbackFcn(app, @InstruButtonPushed, true);
            app.InstruButton.Icon = 'instrus.png';
            app.InstruButton.Visible = 'off';
            app.InstruButton.Tooltip = {'Information '};
            app.InstruButton.Text = 'Instrucciones';
            app.DIMENSIONAMIENTOFOTOVOLTAICOLabel = uilabel(app.UIFigure);
            app.DIMENSIONAMIENTOFOTOVOLTAICOLabel.Text = 'DIMENSIONAMIENTO FOTOVOLTAICO';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'Escudo.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'LogoB.png');
            app.CargasButton = uibutton(app.UIFigure, 'push');
            app.CargasButton.ButtonPushedFcn = createCallbackFcn(app, @CargasButtonPushed, true);
            app.CargasButton.Icon = fullfile(pathToMLAPP, 'images', 'cargasv.png');
            app.CargasButton.Text = {'Cargas'; ''};
            app.checkLabel = uilabel(app.UIFigure);
            app.checkLabel.Text = 'check';
            app.checkLabel2 = uilabel(app.UIFigure);
            app.checkLabel2.Text = 'check';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Icon = fullfile(pathToMLAPP, 'images', 'calculadora.png');
            app.CalcularButton.Text = 'Calcular';
            app.IdiomaDropDown = uidropdown(app.UIFigure);
            app.IdiomaDropDown.Items = {'Español', 'English'};
            app.IdiomaDropDown.ValueChangedFcn = createCallbackFcn(app, @IdiomaDropDownValueChanged, true);
            app.IdiomaDropDown.Value = 'Español';
            app.NombredelArchivoEditField = uieditfield(app.UIFigure, 'text');
            app.NombredelArchivoEditField.Editable = 'off';
            app.NombredelArchivoEditField.Visible = 'off';
            app.NOMBREDELARCHIVOLabel = uilabel(app.UIFigure);
            app.NOMBREDELARCHIVOLabel.Text = 'NOMBRE DEL ARCHIVO:';
            app.AccederLabel = uilabel(app.UIFigure);
            app.AccederLabel.Text = {'Ingrese la contraseña asignada en caso de no tenerla '; 'ingrese al link ..'};
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://forms.gle/kafhFJZKb8LFFmUy8';
            app.Hyperlink.Text = 'Obten tu password';
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditField.ValueChangedFcn = createCallbackFcn(app, @PasswordEditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolarAnalyzer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end