classdef instru < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ASPECTOSATENERENCUENTAPanel     matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PrimerospasosTab                matlab.ui.container.Tab
        Image7                          matlab.ui.control.Image
        AlsubirarchivosconextensinxlsxyxlsPanel  matlab.ui.container.Panel
        Image_2                         matlab.ui.control.Image
        Label2                          matlab.ui.control.Label
        Tree                            matlab.ui.container.Tree
        InstructivoPasosNode            matlab.ui.container.TreeNode
        PasounoNode                     matlab.ui.container.TreeNode
        IntroduccinNode                 matlab.ui.container.TreeNode
        ParmetrosNode                   matlab.ui.container.TreeNode
        FormatosNode                    matlab.ui.container.TreeNode
        PasodosNode                     matlab.ui.container.TreeNode
        PasotresNode                    matlab.ui.container.TreeNode
        PasocuatroNode                  matlab.ui.container.TreeNode
        PasocincoNode                   matlab.ui.container.TreeNode
        Panel_3                         matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        Image10                         matlab.ui.control.Image
        Image9                          matlab.ui.control.Image
        Image8                          matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        AloprimirestebLabel             matlab.ui.control.Label
        AlsubirarchivosconextensintxtPanel  matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel  matlab.ui.control.Label
        FormatossubirarchivosTab        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        txtformatoquenosedetectamesesTab  matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        txtformatoquesedetectamesesTab  matlab.ui.container.Tab
        ConvencionesListBox             matlab.ui.control.ListBox
        ConvencionesListBoxLabel        matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        xlsxformatoquenosedetectamesesTab  matlab.ui.container.Tab
        Image2_3                        matlab.ui.control.Image
        xlsxformatoquesedetectamesesTab  matlab.ui.container.Tab
        ConvencionesListBox_2           matlab.ui.control.ListBox
        ConvencionesListBox_2Label      matlab.ui.control.Label
        Image2_4                        matlab.ui.control.Image
    end


    properties (Access = private)

        titulo = "inicio";

    end

    properties (Access = public)
        Menoapp ;% Description
        Idioma;
        CallingApp;
        instruApp;
    end
    methods (Access = private)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idiomaSeleccionado)
            % menoapp = app.Menoapp;
            % app.Idioma = Idioma;
            app.Idioma = idiomaSeleccionado;
            app.ASPECTOSATENERENCUENTAPanel.Visible = "on";
            expand(app.Tree);
            expand(app.PasounoNode);
            app.Tree.SelectedNodes = app.InstructivoPasosNode;
            app.FormatossubirarchivosTab.Parent = [];
            app.Panel.Visible = "off";
            app.Image9.Visible = "off";
            app.Image10.Visible = "off";
            if strcmp(idiomaSeleccionado, 'English')
                app.InstructivoPasosNode.Text="Instructional-Steps";
             app.PasounoNode.Text="1.Step one";
                app.AloprimirestebLabel.Text="Welcome user, this instruction is intended to" + newline + "teach the first steps to upload the files and configure them." + newline + "So that once the file is configured, you can proceed with the" + newline + "stage process for Photovoltaic analysis";
         app.PasodosNode.Text="2.Step two";
         app.PasotresNode.Text="3.Step three";    
         app.PasocuatroNode.Text="4.Step four";
          app.PasocincoNode.Text="5.Step five";
            app.IntroduccinNode.Text="1.1.Introduction";
            app.ParmetrosNode.Text="1.2.Parameters";
            app.FormatosNode.Text="1.3.Formats";
            app.ASPECTOSATENERENCUENTAPanel.Title="Aspects to consider";
            app.Panel_3.Title=".";


            else


            end

        end

        % Callback function: UIFigure, UIFigure, UIFigure
        function UIFigureCloseRequest(app, event)
            app.Menoapp.InstruButton.Enable = "on";
            delete(app)

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            idiomaSeleccionado = app.Idioma;
            selectedNodes = app.Tree.SelectedNodes;
            switch selectedNodes.NodeData
                case 1
                    if strcmp(idiomaSeleccionado, 'English')
                        app.AloprimirestebLabel.Text = "Welcome user, this instruction is intended to" + newline + "teach the first steps to upload the files and configure them." + newline + "So that once the file is configured, you can proceed with the" + newline + "stage process for Photovoltaic analysis";
                        app.Label.Visible = "off";
                        app.Panel_3.Title =  'start';
                        app.AloprimirestebLabel.Visible = "on";
                        app.titulo = "start";
                        app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                        app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                        app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                        app.Image8.Visible = "on";
                        app.Image9.Visible = "off";
                        app.Image10.Visible = "off";

                    else

                        app.AloprimirestebLabel.Text =  "Bienvenido usuario(a), este instructivo tiene como finalidad" + newline + " enseñar los primeros pasos para subir los archivos y configurarlos." + newline + "Con el motivo de que el archivo una vez configurado, se pueda proceder con el" + newline + " proceso de etapas para el análisis Fotovoltaico" ;
                        app.Label.Visible = "off";
                        app.Panel_3.Title =  'inicio';
                        app.AloprimirestebLabel.Visible = "on";
                        app.titulo = "inicio";
                        app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                        app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                        app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                        app.Image8.Visible = "on";
                        app.Image9.Visible = "off";
                        app.Image10.Visible = "off";

                    end

                case 3
                    if strcmp(idiomaSeleccionado, 'English')
                        app.Label.Visible = "off";
                        app.AloprimirestebLabel.Text = "The user will be able to access from their computer to search for the file " + newline + "that has the data on the different variables that allow the analysis." + newline + newline + "If you already had a file loaded and select the button again " + newline + "but cancel the selection, the program will restart all the options." + newline + newline + "WARNING: The application is NOT responsible for the file that is uploaded to it" + newline + "and is not the appropriate to the established formats.";
                        app.Panel_3.Title ='Step 1- Upload the file';
                        app.AloprimirestebLabel.Visible = "on";
                        app.titulo = "one";
                        app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                        app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                        app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                        app.Image8.Visible = "off";
                        app.Image9.Visible = "on";
                        app.Image10.Visible = "off";
                    else
                        app.Label.Visible = "off";
                        app.AloprimirestebLabel.Text = "El usuario podra acceder desde su computador para buscar el archivo " + newline + "que tienen los datos sobre las diferentes variables que permite el análisis." + newline + newline + "Si usted ya tenia un archivo cargado y  vuelve a seleccionar el botón " + newline + "pero cancela la selección, el programa reiniciara todas las opciones." + newline + newline + "ADVERTENCIA: El aplicativo NO se hace responsable del archivo que suba al mismo" + newline + "y no sea el adecuado a los formatos establecidos.";
                        app.Panel_3.Title ='Paso 1- Subir el archivo';
                        app.AloprimirestebLabel.Visible = "on";
                        app.titulo = "uno";
                        app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                        app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                        app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                        app.Image8.Visible = "off";
                        app.Image9.Visible = "on";
                        app.Image10.Visible = "off";
                    end
                case 4
                    if strcmp(idiomaSeleccionado, 'English')
                        app.Label.Visible = "off";
                        app.AlsubirarchivosconextensintxtPanel.Visible = "on";
                        app.AlsubirarchivosconextensintxtPanel.Title = "When uploading files with a .txt extension";
                        app.AlsubirarchivosconextensinxlsxyxlsPanel.Title= "When uploading files with .xlsx and .xls extension";
                        app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "on";
                        app.AloprimirestebLabel.Visible = "off";
                        app.titulo = "Parameters";
                        app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "on";
                        app.Image8.Visible = "off";
                        app.Image9.Visible = "off";
                        app.Image10.Visible = "off";
                        app.Label_3.Text ="1. Make sure the file was exported with a delimiter of spaces."+ newline + "2. Make sure decimals are marked with a dot (.).";
                        app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Text="The file extensions that the application handles are: ''.txt'' ''.xlsx '' ''.xls''";
                        app.Label2.Text = "1. Make sure that in Excel, the ''Start'' option, in the ''Number'' format"+ newline +" of the data is in ''Number'' ."+ newline +"2. Make sure that in Excel, for decimals it is a comma (,) and no dot (.)";
                    else
                        app.Label.Visible = "off";
                        app.AlsubirarchivosconextensintxtPanel.Visible = "on";
                        app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "on";
                        app.AloprimirestebLabel.Visible = "off";
                        app.titulo = "Parametros";
                        app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "on";
                        app.Image8.Visible = "off";
                        app.Image9.Visible = "off";
                        app.Image10.Visible = "off";
                    end

                case 5

                    if strcmp(idiomaSeleccionado, 'English')

                        app.Label.Text = "Note: One of the processes in the analysis of the Photovoltaic design is the "+ newline + "radiation analysis, where the behavior of the "+ newline + "data is seen through tables and their "+ newline + "inclination settings. for the panels." + newline + newline + "The default application allows the calculation to be carried out using" + newline + "all the data. If you want to modify it in a specific month, " + newline + "you need to configure the file format for the extensions" + newline + "or, failing that, modify it within the application month by month''";
                        app.Label.Visible = "on";
                        app.Panel_3.Title = 'Step 1- Upload the file';
                        app.AloprimirestebLabel.Visible = "off";
                        app.titulo = "formats";
                        app.Image8.Visible = "off";
                        app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                        app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                        app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                        app.FormatossubirarchivosTab.Parent = app.TabGroup;
                        app.Panel.Visible = "off";
                        app.Image9.Visible = "off";
                        app.Image10.Visible = "off";
                    else
                        app.Label.Text = "Nota: Uno de los procesos en el análisis del diseño Fotovoltaico es el" + newline + "analisis de radiación, donde se ve el comportamiento de los" + newline + "datos a traves de tablas y sus ajustes de" + newline + "inclinación para los paneles." + newline + newline + "El aplicativo por defecto permite realiza el calculo usando" + newline + "todos los datos. Si desea modificarlo en algún mes especifico, " + newline + "es necesario que configure el formato del archivo para las extensiones" + newline + "o en su defecto modifique dentro del aplicativo mes a mes''";
                        app.Label.Visible = "on";
                        app.Panel_3.Title = 'Paso 1- Subir el archivo';
                        app.AloprimirestebLabel.Visible = "off";
                        app.titulo = "formatos";
                        app.Image8.Visible = "off";
                        app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                        app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                        app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                        app.FormatossubirarchivosTab.Parent = app.TabGroup;
                        app.Panel.Visible = "off";
                        app.Image9.Visible = "off";
                        app.Image10.Visible = "off";
                    end

                case 6
                    if strcmp(idiomaSeleccionado, 'English')
                        app.Label.Visible = "off";
                        app.AloprimirestebLabel.Text = "This button is enabled once the file has been uploaded to the application." + newline + "Once the button is clicked, it will open the window to select " + newline + "the variables with respect to the file. Once the window is opened, it is not" + newline + " will close until the necessary data has been saved and calculated." + newline + newline + "WARNING: Once you enter the ''Radiation'' button window" + newline + "You cannot get out of it until you have calculated and saved the radiation to be minimal." + newline + " or average and selected the inclination angle.";
                        app.Panel_3.Title = "Step 2 - Select the variables";
                        app.AloprimirestebLabel.Visible = "on";
                        app.titulo = "dos";
                        app.Image8.Visible = "off";
                        app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                        app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                        app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                        app.Image9.Visible = "off";
                        app.Image10.Visible = "on";
                        app.Image10.ImageSource = 'boton1.jpg';
                    else

                        app.Label.Visible = "off";
                        app.AloprimirestebLabel.Text = "Este botón se habilitara una vez el archivo fue cargado al aplicativo. " + newline + "Una vez que se da click al botón, abrirá la ventana para seleccionar " + newline + "las variables  con respecto al archivo. Una vez abierto la ventana no se" + newline + "cerrara hasta haber guardado y calculado los datos necesarios ." + newline + newline + "ADVERTENCIA: Una vez que usted entra a la ventana del botón ''Radiación''" + newline + "no puede salir de ella hasta haber calculado y Guardado la radiacion ya sea minima " + newline + "  o promedio y seleccionado el angulo de inclinacion .";
                        app.Panel_3.Title = "Paso 2 - Seleccionar las variables";
                        app.AloprimirestebLabel.Visible = "on";
                        app.titulo = "dos";
                        app.Image8.Visible = "off";
                        app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                        app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                        app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                        app.Image9.Visible = "off";
                        app.Image10.Visible = "on";
                        app.Image10.ImageSource = 'boton1.jpg';

                    end
                case 7
                     if strcmp(idiomaSeleccionado, 'English')
                    app.Label.Visible = "off";
                    app.AloprimirestebLabel.Text = "This button will be enabled once the radiation values are saved, you can " + newline + "modify the Radiation with the previous button." + newline + newline + "Once the button is clicked, the window will open to perform"+ newline +" the loading data collection process" + newline + " for the file and the variables that you previously configured." ;
                    app.Panel_3.Title = "Step 3 - Process";
                    app.AloprimirestebLabel.Visible = "on";
                    app.titulo = "tree";
                    app.Image8.Visible = "off";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    app.Image9.Visible = "off";
                    app.Image10.Visible = "on";
                    app.Image10.ImageSource = 'boton2.jpg';
                     else
                     app.Label.Visible = "off";
                    app.AloprimirestebLabel.Text = "Este botón se habilitara una vez se halla guardado los valores de radiación,se podra " + newline + "modificar la Radiación con el botón anterior." + newline +  newline + "Una vez que se da click al botón, abrirá la ventana para realizar"+ newline +" el proceso de toma de datos de carga" + newline + " para el archivo y las variables que usted configuro previamente.";
                    app.Panel_3.Title = "Paso 3 - Comenzar proceso";
                    app.AloprimirestebLabel.Visible = "on";
                    app.titulo = "tres";
                    app.Image8.Visible = "off";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    app.Image9.Visible = "off";
                    app.Image10.Visible = "on";
                    app.Image10.ImageSource = 'boton2.jpg';
                     
                     
                     end
                     
                case 9
                  if strcmp(idiomaSeleccionado, 'English')
                    app.Label.Visible = "off";
                    app.AloprimirestebLabel.Text = "This button will be enabled once the Load values have been saved, you can " + newline + "modify the Load with the previous button." + newline + newline + "Once the button is clicked, the window will open to perform "+ newline +" the final process where the " + newline + " type of panel, type of inverter and battery are selected.";
                    app.Panel_3.Title = "Step 4 - Process";
                    app.AloprimirestebLabel.Visible = "on";
                    app.titulo = "tree";
                    app.Image8.Visible = "off";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    app.Image9.Visible = "off";
                    app.Image10.Visible = "on";
                    app.Image10.ImageSource = 'boton3.jpg';
                     else
                     app.Label.Visible = "off";
                    app.AloprimirestebLabel.Text = "Este botón se habilitara una vez se halla guardado los valores de las Cargas,se podra " + newline + "modificar la Carga con el botón anterior." + newline +  newline + "Una vez que se da click al botón, abrirá la ventana para realizar"+ newline +" el proceso final donde se selecciona" + newline + " el tipo de panel ,tipo de inversor y de bateria.";
                    app.Panel_3.Title = "Paso 4 - Proceso";
                    app.AloprimirestebLabel.Visible = "on";
                    app.titulo = "cuatro";
                    app.Image8.Visible = "off";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    app.Image9.Visible = "off";
                    app.Image10.Visible = "on";
                    app.Image10.ImageSource = 'boton3.jpg';
                
                  end
  case 11
                  if strcmp(idiomaSeleccionado, 'English')
                    app.Label.Visible = "off";
                    app.AloprimirestebLabel.Text = "This button will be enabled once the values of the calculate window are saved" + newline + "within this same window" + newline + newline + "Once the button is clicked, the window to perform will open"+ newline +"the configuration of the batteries that complement the system" + newline + "the panel connection configuration can be carried out according to system requirements and user ease";
                    app.Panel_3.Title = "Step 5 - Process";
                    app.AloprimirestebLabel.Visible = "on";
                    app.titulo = "tree";
                    app.Image8.Visible = "off";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    app.Image9.Visible = "off";
                    app.Image10.Visible = "on";
                    app.Image10.ImageSource = 'boton5.jpg';
                     else
                     app.Label.Visible = "off";
                    app.AloprimirestebLabel.Text = "Este botón se habilitara una vez se halla guardado los valores de la ventana calcular  " + newline + "dentro de esta misma ventana" + newline +  newline + "Una vez que se da click al botón, abrirá la ventana para realizar"+ newline +"la configuracion de las baterias que suplen el sistema" + newline + " se puede realizar la configuracion de conexion de los paneles según requerimientos del sistema y facilidad del usuario";
                    app.Panel_3.Title = "Paso 5 - Proceso";
                    app.AloprimirestebLabel.Visible = "on";
                    app.titulo = "cuatro";
                    app.Image8.Visible = "off";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    app.Image9.Visible = "off";
                    app.Image10.Visible = "on";
                    app.Image10.ImageSource = 'boton4.jpg';
                
                  end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ASPECTOSATENERENCUENTAPanel = uipanel(app.UIFigure);
            app.ASPECTOSATENERENCUENTAPanel.Title = 'ASPECTOS A TENER EN CUENTA';
            app.TabGroup = uitabgroup(app.ASPECTOSATENERENCUENTAPanel);
            app.PrimerospasosTab = uitab(app.TabGroup);
            app.PrimerospasosTab.Title = 'Primeros pasos';
            app.Panel_3 = uipanel(app.PrimerospasosTab);
            app.Panel_3.Title = '.';
            app.Panel_2 = uipanel(app.Panel_3);
            app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel = uilabel(app.Panel_2);
            app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = 'off';
            app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Text = 'Las extensiones de archivo que el aplicativo maneja son:  ''''.txt''''     ''''.xlsx ''''     ".xls" .';
            app.Label = uilabel(app.Panel_2);
            app.Label.Visible = 'off';
            app.Label.Text = {'Nota: Uno de los procesos en el análisis del diseño eólico es la '; '         distribución del viento, donde se ve el comportamiento de los '; 'datos del  viento a traves  de Histogramas  y sus ajustes de '; 'distribución. '; ''; 'El aplicativo por defecto permite realizar el histograma usando '; 'todos los datos. Si desea hacerlo para algún mes especifico,'; 'es necesario que configure el formato del archivo para las extensiones'; 'soportadas como se indica en la  pestaña "Formatos"'; ''};
            app.AlsubirarchivosconextensintxtPanel = uipanel(app.Panel_2);
            app.AlsubirarchivosconextensintxtPanel.Title = 'Al subir archivos con extensión .txt';
            app.AlsubirarchivosconextensintxtPanel.Visible = 'off';
            app.Image = uiimage(app.AlsubirarchivosconextensintxtPanel);
            app.Image.ImageSource = 'coma.png';
            app.Label_3 = uilabel(app.AlsubirarchivosconextensintxtPanel);
            app.Label_3.Text = {'1. Asegurese que el archivo fue exportado con un delimitador'; ' de espacios.'; ''; '2. Asegurese que los decimales esten señalados con punto (.).'; ''};
            app.AloprimirestebLabel = uilabel(app.Panel_2);
            app.AloprimirestebLabel.Text = {'Bienvenido usuario(a), este instructivo tiene como finalidad '; 'enseñar los primeros pasos para subir los archivos y configurarlos.'; 'Con el motivo de que el archivo una vez configurado, se pueda proceder con el '; 'proceso de etapas para el análisis Fotovoltaico.'};
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Visible = 'off';
            app.Image8 = uiimage(app.Panel_2);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'images', 'BienvenidoInstru.jpg');
            app.Image9 = uiimage(app.Panel_2);
            app.Image9.ImageSource = 'subirarc.png';
            app.Image10 = uiimage(app.Panel_2);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'images', 'boton1.jpg');
            app.Tree = uitree(app.PrimerospasosTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.InstructivoPasosNode = uitreenode(app.Tree);
            app.InstructivoPasosNode.NodeData = 1;
            app.InstructivoPasosNode.Icon = 'instrucciones1.svg';
            app.InstructivoPasosNode.Text = 'Instructivo-Pasos';
            app.PasounoNode = uitreenode(app.InstructivoPasosNode);
            app.PasounoNode.NodeData = 2;
            app.PasounoNode.Text = '1.Paso uno';
            app.IntroduccinNode = uitreenode(app.PasounoNode);
            app.IntroduccinNode.NodeData = 3;
            app.IntroduccinNode.Text = '1.1.Introducción';
            app.ParmetrosNode = uitreenode(app.PasounoNode);
            app.ParmetrosNode.NodeData = 4;
            app.ParmetrosNode.Text = '1.2.Parámetros';
            app.FormatosNode = uitreenode(app.PasounoNode);
            app.FormatosNode.NodeData = 5;
            app.FormatosNode.Text = '1.3.Formatos';
            app.PasodosNode = uitreenode(app.InstructivoPasosNode);
            app.PasodosNode.NodeData = 6;
            app.PasodosNode.Text = '2.Paso dos';
            app.PasotresNode = uitreenode(app.InstructivoPasosNode);
            app.PasotresNode.NodeData = 7;
            app.PasotresNode.Text = '3.Paso tres';
            app.PasocuatroNode = uitreenode(app.InstructivoPasosNode);
            app.PasocuatroNode.NodeData = 9;
            app.PasocuatroNode.Text = '4.Paso cuatro';
            app.PasocincoNode = uitreenode(app.InstructivoPasosNode);
            app.PasocincoNode.NodeData = 11;
            app.PasocincoNode.Text = '5.Paso cinco';
            app.AlsubirarchivosconextensinxlsxyxlsPanel = uipanel(app.PrimerospasosTab);
            app.AlsubirarchivosconextensinxlsxyxlsPanel.Title = 'Al subir archivos con extensión .xlsx y .xls';
            app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = 'off';
            app.Label2 = uilabel(app.AlsubirarchivosconextensinxlsxyxlsPanel);
            app.Label2.Text = {'1. Asegurese que en el excel, la opción "Inicio", en el cuadro del '; 'Formato de "Número" de los datos estén en "Número" .'; ''; '2. Asegurese que en el excel,  para los decimales sea coma (,) y '; 'no punto (.)'; ''};
            app.Image_2 = uiimage(app.AlsubirarchivosconextensinxlsxyxlsPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'images', 'coma.png');
            app.Image7 = uiimage(app.PrimerospasosTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'images', 'Logo.png');
            app.FormatossubirarchivosTab = uitab(app.TabGroup);
            app.FormatossubirarchivosTab.Title = 'Formatos - subir archivos';
            app.TabGroup2 = uitabgroup(app.FormatossubirarchivosTab);
            app.txtformatoquenosedetectamesesTab = uitab(app.TabGroup2);
            app.txtformatoquenosedetectamesesTab.Title = '.txt formato que no se detecta meses';
            app.Image2 = uiimage(app.txtformatoquenosedetectamesesTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'txtlogo.png');
            app.txtformatoquesedetectamesesTab = uitab(app.TabGroup2);
            app.txtformatoquesedetectamesesTab.Title = '.txt formato que se detecta meses';
            app.Image2_2 = uiimage(app.txtformatoquesedetectamesesTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'images', 'txtlogo.png');
            app.ConvencionesListBoxLabel = uilabel(app.txtformatoquesedetectamesesTab);
            app.ConvencionesListBoxLabel.Text = 'Convenciones';
            app.ConvencionesListBox = uilistbox(app.txtformatoquesedetectamesesTab);
            app.ConvencionesListBox.Items = {'Enero : ene', 'Febrero : feb', 'Marzo : mar', 'Abril : abr', 'Mayo : may', 'Junio : jun', 'Julio : jul', 'Agosto : ago', 'Septiembre :  sep', 'Octubre : oct', 'Noviembre : nov', 'Diciembre : dic'};
            app.ConvencionesListBox.Value = 'Enero : ene';
            app.xlsxformatoquenosedetectamesesTab = uitab(app.TabGroup2);
            app.xlsxformatoquenosedetectamesesTab.Title = '.xlsx formato que no se detecta meses';
            app.Image2_3 = uiimage(app.xlsxformatoquenosedetectamesesTab);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'images', 'xlslogo.jpg');
            app.xlsxformatoquesedetectamesesTab = uitab(app.TabGroup2);
            app.xlsxformatoquesedetectamesesTab.Title = '.xlsx formato  que se detecta meses';
            app.Image2_4 = uiimage(app.xlsxformatoquesedetectamesesTab);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'images', 'xlslogo.jpg');
            app.ConvencionesListBox_2Label = uilabel(app.xlsxformatoquesedetectamesesTab);
            app.ConvencionesListBox_2Label.Text = 'Convenciones';
            app.ConvencionesListBox_2 = uilistbox(app.xlsxformatoquesedetectamesesTab);
            app.ConvencionesListBox_2.Items = {'Enero : ene', 'Febrero : feb', 'Marzo : mar', 'Abril : abr', 'Mayo : may', 'Junio : jun', 'Julio : jul', 'Agosto : ago', 'Septiembre :  sep', 'Octubre : oct', 'Noviembre : nov', 'Diciembre : dic'};
            app.ConvencionesListBox_2.Value = 'Enero : ene';
            app.Panel = uipanel(app.ASPECTOSATENERENCUENTAPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = instru(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end